classdef LESSONMODEMAIN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Menu              matlab.ui.container.Menu
        PracticeModeMenu  matlab.ui.container.Menu
        QuizModeLabel     matlab.ui.control.Label
        PYTHLESSON        matlab.ui.control.Button
        trigButton        matlab.ui.control.Button
        Button            matlab.ui.control.Button
        LESSONSLabel      matlab.ui.control.Label
    end

  
    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PYTHLESSON
        function PYTHLESSONPushed(app, event)
            app.QuizModeLabel.Text = ' Taking you to Lesson Mode: Pythagorean Theorem... 📚';
            app.QuizModeLabel.BackgroundColor = [0.96 0.47 0.16];
            pause(2.0);
            app.QuizModeLabel.Text = '';
          
            TRIGLESSON();

            delete(app);
        end

        % Button pushed function: trigButton
        function trigButtonPushed(app, event)
            app.QuizModeLabel.Text = ' Taking you to Lesson Mode: Trigonometric Functions... 📚';
            app.QuizModeLabel.BackgroundColor = [0.96 0.47 0.16];
            pause(2.0);
            app.QuizModeLabel.Text = '';
          
            TRIG_FUNC();

            delete(app);
        end

        % Menu selected function: PracticeModeMenu
        function PracticeModeMenuSelected(app, event)
            app.QuizModeLabel.Text = ' Taking you to Practice Mode... 📝';
            app.QuizModeLabel.BackgroundColor = [0.96 0.47 0.16];
            pause(2.0);
            app.QuizModeLabel.Text = '';
          
            TRIGQUIZ();

            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.PracticeModeMenu = uimenu(app.Menu);
            app.PracticeModeMenu.MenuSelectedFcn = createCallbackFcn(app, @PracticeModeMenuSelected, true);
            app.PracticeModeMenu.Text = 'Practice Mode';
            app.LESSONSLabel = uilabel(app.UIFigure);
            app.LESSONSLabel.Text = 'LESSONS';
            app.Button = uibutton(app.UIFigure, 'push');
            app.trigButton = uibutton(app.UIFigure, 'push');
            app.trigButton.ButtonPushedFcn = createCallbackFcn(app, @trigButtonPushed, true);
            app.trigButton.Icon = fullfile(pathToMLAPP, 'TRIGFUNC.png');
            app.trigButton.Text = '';
            app.PYTHLESSON = uibutton(app.UIFigure, 'push');
            app.PYTHLESSON.ButtonPushedFcn = createCallbackFcn(app, @PYTHLESSONPushed, true);
            app.PYTHLESSON.Icon = fullfile(pathToMLAPP, 'PYTH.png');
            app.PYTHLESSON.Text = '';
            app.QuizModeLabel = uilabel(app.UIFigure);
            app.QuizModeLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LESSONMODEMAIN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end